classdef Shamir < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        TabGroup                 matlab.ui.container.TabGroup
        Generate                 matlab.ui.container.Tab
        ShamirsSecretSharingAlgorithmLabel  matlab.ui.control.Label
        EnterthesecretandGeneratesharesLabel  matlab.ui.control.Label
        SecretkeyEditFieldLabel  matlab.ui.control.Label
        SecretkeyEditField       matlab.ui.control.EditField
        GenerateSharesButton     matlab.ui.control.Button
        Share1Label              matlab.ui.control.Label
        Share2Label              matlab.ui.control.Label
        Share3Label              matlab.ui.control.Label
        Share4Label              matlab.ui.control.Label
        Reconstruct              matlab.ui.container.Tab
        ShamirsSecretSharingAlgorithmLabel_2  matlab.ui.control.Label
        EntersharestoreconstrcutsecretkeyLabel  matlab.ui.control.Label
        stShareDropDownLabel     matlab.ui.control.Label
        stShareDropDown          matlab.ui.control.DropDown
        ndShareDropDownLabel     matlab.ui.control.Label
        ndShareDropDown          matlab.ui.control.DropDown
        ReconstructButton        matlab.ui.control.Button
        stShareEditFieldLabel    matlab.ui.control.Label
        stShareEditField         matlab.ui.control.EditField
        ndShareEditFieldLabel    matlab.ui.control.Label
        ndShareEditField         matlab.ui.control.EditField
        Label                    matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GenerateSharesButton
        function GenerateSharesButtonPushed(app, event)
            secret=app.SecretkeyEditField.Value;
            val=double(secret);
            prime=131;
            a1=121;
            len=length(val)
            s1='';
            s2='';
            s3='';
            s4='';
            for i=1:len
                s1=append(s1,char(mod(a1*1+val(i),prime)));
                s2=append(s2,char(mod(a1*2+val(i),prime)));
                s3=append(s3,char(mod(a1*3+val(i),prime)));
                s4=append(s4,char(mod(a1*4+val(i),prime)));
            end
            app.Share1Label.Text=append('Share1:  ',s1);
            app.Share2Label.Text=append('Share2:  ',s2);
            app.Share3Label.Text=append('Share3:  ',s3);
            app.Share4Label.Text=append('Share4:  ',s4);

        end

        % Value changed function: SecretkeyEditField
        function SecretkeyEditFieldValueChanged(app, event)
            value = app.SecretkeyEditField.Value;
            
        end

        % Button pushed function: ReconstructButton
        function ReconstructButtonPushed(app, event)
            s2=app.ndShareEditField.Value;
            if(app.stShareDropDown.Value=='Share1')
                x1=1;
            elseif(app.stShareDropDown.Value=='Share2')
                x1=2
            elseif(app.stShareDropDown.Value=='Share3')
                x1=3
            elseif(app.stShareDropDown.Value=='Share4')
                x1=4
            end
            if(app.ndShareDropDown.Value=='Share1')
                x2=1;
            elseif(app.ndShareDropDown.Value=='Share2')
                x2=2
            elseif(app.ndShareDropDown.Value=='Share3')
                x2=3
            elseif(app.ndShareDropDown.Value=='Share4')
                x2=4
            end
            s1=app.stShareEditField.Value
            y1=double(s1)
            y2=double(s2)
            len=length(s1)
            secret1=''
            if(x1==x2)
                app.Label.Text='Same Shares cannot be used for reconstruction';
            elseif(length(s1)~=length(s2))
                app.Label.Text='Shares lengths are not equal please enter correctly';
            else
               for i=1:len
                   res=(-x1*y2(i)/(x2-x1))+(-x2*y1(i)/(x1-x2)) 
                   secret1=append(secret1,char(res))
               end
               app.Label.Text=append('The Secret is: ',secret1)
            end
        end

        % Value changed function: stShareEditField
        function stShareEditFieldValueChanged(app, event)
            value = app.stShareEditField.Value;
            
        end

        % Value changed function: ndShareDropDown
        function ndShareDropDownValueChanged(app, event)
            value = app.ndShareDropDown.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Generate = uitab(app.TabGroup);
            app.Generate.Title = 'Generate';
            app.ShamirsSecretSharingAlgorithmLabel = uilabel(app.Generate);
            app.ShamirsSecretSharingAlgorithmLabel.Text = 'Shamir''s Secret Sharing Algorithm';
            app.EnterthesecretandGeneratesharesLabel = uilabel(app.Generate);
            app.EnterthesecretandGeneratesharesLabel.Text = 'Enter the secret and Generate shares';
            app.SecretkeyEditFieldLabel = uilabel(app.Generate);
            app.SecretkeyEditFieldLabel.Text = 'Secret key:';
            app.SecretkeyEditField = uieditfield(app.Generate, 'text');
            app.SecretkeyEditField.ValueChangedFcn = createCallbackFcn(app, @SecretkeyEditFieldValueChanged, true);
            app.GenerateSharesButton = uibutton(app.Generate, 'push');
            app.GenerateSharesButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateSharesButtonPushed, true);
            app.GenerateSharesButton.Text = 'Generate Shares';
            app.Share1Label = uilabel(app.Generate);
            app.Share1Label.Text = 'Share1:  ';
            app.Share2Label = uilabel(app.Generate);
            app.Share2Label.Text = 'Share2:  ';
            app.Share3Label = uilabel(app.Generate);
            app.Share3Label.Text = 'Share3:  ';
            app.Share4Label = uilabel(app.Generate);
            app.Share4Label.Text = 'Share4:  ';
            app.Reconstruct = uitab(app.TabGroup);
            app.Reconstruct.Title = 'Reconstruct';
            app.ShamirsSecretSharingAlgorithmLabel_2 = uilabel(app.Reconstruct);
            app.ShamirsSecretSharingAlgorithmLabel_2.Text = 'Shamir''s Secret Sharing Algorithm';
            app.EntersharestoreconstrcutsecretkeyLabel = uilabel(app.Reconstruct);
            app.EntersharestoreconstrcutsecretkeyLabel.Text = 'Enter shares to reconstrcut secret key';
            app.stShareDropDownLabel = uilabel(app.Reconstruct);
            app.stShareDropDownLabel.Text = '1st Share:';
            app.stShareDropDown = uidropdown(app.Reconstruct);
            app.stShareDropDown.Items = {'Share1', 'Share2', 'Share3', 'Share4'};
            app.stShareDropDown.Value = 'Share1';
            app.ndShareDropDownLabel = uilabel(app.Reconstruct);
            app.ndShareDropDownLabel.Text = '2nd Share:';
            app.ndShareDropDown = uidropdown(app.Reconstruct);
            app.ndShareDropDown.Items = {'Share1', 'Share2', 'Share3', 'Share4'};
            app.ndShareDropDown.ValueChangedFcn = createCallbackFcn(app, @ndShareDropDownValueChanged, true);
            app.ndShareDropDown.Value = 'Share1';
            app.ReconstructButton = uibutton(app.Reconstruct, 'push');
            app.ReconstructButton.ButtonPushedFcn = createCallbackFcn(app, @ReconstructButtonPushed, true);
            app.ReconstructButton.Text = 'Reconstruct';
            app.stShareEditFieldLabel = uilabel(app.Reconstruct);
            app.stShareEditFieldLabel.Text = '1st Share:';
            app.stShareEditField = uieditfield(app.Reconstruct, 'text');
            app.stShareEditField.ValueChangedFcn = createCallbackFcn(app, @stShareEditFieldValueChanged, true);
            app.ndShareEditFieldLabel = uilabel(app.Reconstruct);
            app.ndShareEditFieldLabel.Text = '2nd Share:';
            app.ndShareEditField = uieditfield(app.Reconstruct, 'text');
            app.Label = uilabel(app.Reconstruct);
            app.Label.Text = ' ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Shamir

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end